classdef demoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        InitRestartButton            matlab.ui.control.Button
        loadeddomainLabel            matlab.ui.control.Label
        nogenerationsEditFieldLabel  matlab.ui.control.Label
        nogenerationsEditField       matlab.ui.control.NumericEditField
        domainDropDownLabel          matlab.ui.control.Label
        domainDropDown               matlab.ui.control.DropDown
        featurexaxisDropDownLabel    matlab.ui.control.Label
        featurexaxisDropDown         matlab.ui.control.DropDown
        featureyaxisDropDownLabel    matlab.ui.control.Label
        featureyaxisDropDown         matlab.ui.control.DropDown
        RedrawButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        p % Algorithm configuration
        d % Domain configuration
        map % Current map object
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            DOMAIN = 'dropwave'; %npoly dropwave shubert4
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            
            ALGORITHM = 'vElites';
            rmpath('mapElites'); rmpath('vElites'); addpath(ALGORITHM);
            % Load configuration of domain and V-Elites
            app.d = domain;
            app.p = defaultParamSet(4);
            app.nogenerationsEditField.Value = app.p.nGens;
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            
            app.featurexaxisDropDown.Items = app.d.featureLabels;
            app.featureyaxisDropDown.Items = app.d.featureLabels;   
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};            
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};
            
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            
            sobSequence         = scramble(sobolset(app.d.dof,'Skip',1e3),'MatousekAffineOwen');
            sobPoint            = 1;
            initSamples         = range(app.d.ranges).*sobSequence(sobPoint:(sobPoint+app.p.numInitSamples)-1,:)+app.d.ranges(1);
            [fitness, values]   = app.d.fitfun(initSamples);
            obsMap              = createMap(app.d.dof, app.p.maxBins, app.p.competeDistance, app.p.infReplacement);
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, values, obsMap, app.d);
            obsMap = updateMap(replaced,replacement,obsMap,fitness,initSamples,features);
            
            [app.map, ~, ~, ~, allMaps] = vElites(app.d.fitfun,obsMap,app.p,app.d);
            
            % Show data
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
            app.UIAxes.Title.String = 'Fitness';
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            value = app.domainDropDown.Value;
            addpath(genpath('.'));
            DOMAIN = value;
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            ALGORITHM = 'vElites';
            rmpath('mapElites'); rmpath('vElites'); addpath(ALGORITHM);
            app.d = domain;
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            app.featurexaxisDropDown.Items = app.d.featureLabels;
            app.featureyaxisDropDown.Items = app.d.featureLabels;  
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};            
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};            
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(2) = col;            
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InitRestartButton = uibutton(app.UIFigure, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Text = 'Init/Restart';
            app.loadeddomainLabel = uilabel(app.UIFigure);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.nogenerationsEditFieldLabel = uilabel(app.UIFigure);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.nogenerationsEditField.Limits = [1 10000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.domainDropDownLabel = uilabel(app.UIFigure);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.UIFigure);
            app.domainDropDown.Items = {'dropwave', 'shubert4', 'npoly'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Value = 'dropwave';
            app.featurexaxisDropDownLabel = uilabel(app.UIFigure);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.UIFigure);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.UIFigure);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.UIFigure);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Value = 'default';
            app.RedrawButton = uibutton(app.UIFigure, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demoGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end